classdef BOP3_3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        SolveTypeEditField              matlab.ui.control.NumericEditField
        SolveTypeEditFieldLabel         matlab.ui.control.Label
        PlotsensstudyCheckBox           matlab.ui.control.CheckBox
        TsDiagramCheckBox               matlab.ui.control.CheckBox
        iseneff                         matlab.ui.control.NumericEditField
        TabGroup                        matlab.ui.container.TabGroup
        HomeTab                         matlab.ui.container.Tab
        Image_2                         matlab.ui.control.Image
        Image                           matlab.ui.control.Image
        WelcomeToBOPLabel               matlab.ui.control.Label
        Ver33Label                      matlab.ui.control.Label
        BOPBraytonOptimizationProgramLabel  matlab.ui.control.Label
        CycleTab                        matlab.ui.container.Tab
        QinMWEditField                  matlab.ui.control.NumericEditField
        QinMWEditFieldLabel             matlab.ui.control.Label
        SingleSolveButton               matlab.ui.control.Button
        CHTREditField                   matlab.ui.control.NumericEditField
        CHTREditFieldLabel              matlab.ui.control.Label
        CLTREditField                   matlab.ui.control.NumericEditField
        CLTREditFieldLabel              matlab.ui.control.Label
        PreheatEditField                matlab.ui.control.NumericEditField
        PreheatEditFieldLabel           matlab.ui.control.Label
        UAsplitEditField                matlab.ui.control.NumericEditField
        UAsplitEditFieldLabel           matlab.ui.control.Label
        UAEditField                     matlab.ui.control.NumericEditField
        UAEditFieldLabel                matlab.ui.control.Label
        RecompFractionEditField         matlab.ui.control.NumericEditField
        RecompFractionEditFieldLabel    matlab.ui.control.Label
        RcompeffEditField               matlab.ui.control.NumericEditField
        RcompeffEditFieldLabel          matlab.ui.control.Label
        CompeffEditField                matlab.ui.control.NumericEditField
        CompeffEditFieldLabel           matlab.ui.control.Label
        TurbeffEditField                matlab.ui.control.NumericEditField
        TurbeffEditFieldLabel           matlab.ui.control.Label
        RecupeffEditField               matlab.ui.control.NumericEditField
        RecupeffEditFieldLabel          matlab.ui.control.Label
        PratioEditField                 matlab.ui.control.NumericEditField
        PratioEditFieldLabel            matlab.ui.control.Label
        PminkPaEditField                matlab.ui.control.NumericEditField
        PminkPaEditFieldLabel           matlab.ui.control.Label
        TmaxKEditField                  matlab.ui.control.NumericEditField
        TmaxKEditFieldLabel             matlab.ui.control.Label
        NetWorkMWEditField              matlab.ui.control.NumericEditField
        NetWorkMWEditFieldLabel         matlab.ui.control.Label
        TminKEditField                  matlab.ui.control.NumericEditField
        TminKEditFieldLabel             matlab.ui.control.Label
        DefaultButton                   matlab.ui.control.Button
        DyrebyRecompCheckBox            matlab.ui.control.CheckBox
        CycleComponentsLabel_2          matlab.ui.control.Label
        RecompressionButton             matlab.ui.control.StateButton
        ReheatButton                    matlab.ui.control.StateButton
        RecuperationButton              matlab.ui.control.StateButton
        SensitivityStudyTab             matlab.ui.container.Tab
        SensitivityStudySolverLabel     matlab.ui.control.Label
        VariableLabel                   matlab.ui.control.Label
        Variable1DropDown               matlab.ui.control.DropDown
        RangeEditField_2                matlab.ui.control.NumericEditField
        RangeEditField_2Label           matlab.ui.control.Label
        RangeEditField                  matlab.ui.control.NumericEditField
        RangeEditFieldLabel             matlab.ui.control.Label
        ResolutionEditField_2           matlab.ui.control.NumericEditField
        ResolutionEditField_2Label      matlab.ui.control.Label
        ResolutionEditField             matlab.ui.control.NumericEditField
        ResolutionEditFieldLabel        matlab.ui.control.Label
        MinValueEditField_2             matlab.ui.control.NumericEditField
        MinValueEditField_2Label        matlab.ui.control.Label
        MinValueEditField               matlab.ui.control.NumericEditField
        MinValueEditFieldLabel          matlab.ui.control.Label
        Variable2Label                  matlab.ui.control.Label
        SecondVarDropdown               matlab.ui.control.DropDown
        StartButton                     matlab.ui.control.Button
        MultiVarButton                  matlab.ui.control.StateButton
        SingleVarButton                 matlab.ui.control.StateButton
        OptimizeTab                     matlab.ui.container.Tab
        MaxLabel                        matlab.ui.control.Label
        MinLabel                        matlab.ui.control.Label
        CHTRBoundsEditField_2           matlab.ui.control.NumericEditField
        CLTRBoundsEditField_2           matlab.ui.control.NumericEditField
        PreheatBoundsEditField_2        matlab.ui.control.NumericEditField
        RecupeffBoundsEditField_2       matlab.ui.control.NumericEditField
        NetWorkBoundsEditField_2        matlab.ui.control.NumericEditField
        RecompFractionBoundsEditField_2  matlab.ui.control.NumericEditField
        PratioBoundsEditField_2         matlab.ui.control.NumericEditField
        CHTRCheckBox                    matlab.ui.control.CheckBox
        CLTRCheckBox                    matlab.ui.control.CheckBox
        PReheatCheckBox                 matlab.ui.control.CheckBox
        RecupeffCheckBox                matlab.ui.control.CheckBox
        NetWorkCheckBox                 matlab.ui.control.CheckBox
        StartButton_2                   matlab.ui.control.Button
        CHTRBoundsEditField             matlab.ui.control.NumericEditField
        CHTRBoundsEditFieldLabel        matlab.ui.control.Label
        CLTRBoundsEditField             matlab.ui.control.NumericEditField
        CLTRBoundsEditFieldLabel        matlab.ui.control.Label
        PreheatBoundsEditField          matlab.ui.control.NumericEditField
        PreheatBoundsEditFieldLabel     matlab.ui.control.Label
        RecupeffBoundsEditField         matlab.ui.control.NumericEditField
        RecupeffBoundsEditFieldLabel    matlab.ui.control.Label
        NetWorkBoundsEditField          matlab.ui.control.NumericEditField
        NetWorkBoundsEditFieldLabel     matlab.ui.control.Label
        UAsplitBoundsEditField_2        matlab.ui.control.NumericEditField
        UABoundsEditField_2             matlab.ui.control.NumericEditField
        PminBoundsEditField_2           matlab.ui.control.NumericEditField
        TminBoundsEditField_2           matlab.ui.control.NumericEditField
        TmaxBoundsEditField_2           matlab.ui.control.NumericEditField
        UAsplitBoundsEditField          matlab.ui.control.NumericEditField
        UAsplitBoundsEditFieldLabel     matlab.ui.control.Label
        RecompFractionBoundsEditField   matlab.ui.control.NumericEditField
        RecompFractionBoundsEditFieldLabel  matlab.ui.control.Label
        UABoundsEditField               matlab.ui.control.NumericEditField
        UABoundsEditFieldLabel          matlab.ui.control.Label
        TmaxBoundsEditField             matlab.ui.control.NumericEditField
        TmaxBoundsEditFieldLabel        matlab.ui.control.Label
        PratioBoundsEditField           matlab.ui.control.NumericEditField
        PratioBoundsEditFieldLabel      matlab.ui.control.Label
        PminBoundsEditField             matlab.ui.control.NumericEditField
        PminBoundsEditFieldLabel        matlab.ui.control.Label
        TminBoundsEditField             matlab.ui.control.NumericEditField
        TminBoundsEditFieldLabel        matlab.ui.control.Label
        UAsplitCheckBox                 matlab.ui.control.CheckBox
        UACheckBox                      matlab.ui.control.CheckBox
        RecompFractionCheckBox          matlab.ui.control.CheckBox
        PratioCheckBox                  matlab.ui.control.CheckBox
        PminCheckBox                    matlab.ui.control.CheckBox
        TmaxCheckBox                    matlab.ui.control.CheckBox
        ParameterstooptimizeLabel       matlab.ui.control.Label
        TminCheckBox                    matlab.ui.control.CheckBox
        MaxGenerationsEditField         matlab.ui.control.NumericEditField
        MaxGenerationsEditFieldLabel    matlab.ui.control.Label
        GenerationSizeEditField         matlab.ui.control.NumericEditField
        GenerationSizeEditFieldLabel    matlab.ui.control.Label
        OptimizerGeneticAlgorithmLabel  matlab.ui.control.Label
        ResultsTab                      matlab.ui.container.Tab
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
     app.SolveTypeEditField.Value = 2; %% Sets Default as Sens Study


     app.TminBoundsEditField.Enable = 0;
     app.TminBoundsEditField_2.Enable = 0;

     app.TmaxBoundsEditField.Enable = 0;
     app.TmaxBoundsEditField_2.Enable = 0;

     app.PminBoundsEditField.Enable = 0;
     app.PminBoundsEditField_2.Enable = 0;

     app.PratioBoundsEditField.Enable = 0;
     app.PratioBoundsEditField_2.Enable = 0;

     app.RecompFractionBoundsEditField.Enable = 0;
     app.RecompFractionBoundsEditField_2.Enable = 0;

     app.RecupeffBoundsEditField.Enable = 0;
     app.RecupeffBoundsEditField_2.Enable = 0;

     app.PreheatBoundsEditField.Enable = 0;
     app.PreheatBoundsEditField_2.Enable = 0;

     app.UABoundsEditField.Enable = 0;
     app.UABoundsEditField_2.Enable = 0;

     app.UAsplitBoundsEditField.Enable = 0;
     app.UAsplitBoundsEditField_2.Enable = 0;

     app.CLTRBoundsEditField.Enable = 0;
     app.CLTRBoundsEditField_2.Enable = 0;

     app.CHTRBoundsEditField.Enable = 0;
     app.CHTRBoundsEditField_2.Enable = 0;

     app.NetWorkBoundsEditField.Enable = 0;
     app.NetWorkBoundsEditField_2.Enable = 0;

        end

        % Value changed function: Variable1DropDown
        function Variable1DropDownValueChanged(app, event)
       app.Variable1DropDown.ItemsData = 1:numel(app.Variable1DropDown.Items);
        
        end

        % Value changed function: TminKEditField
        function TminKEditFieldValueChanged(app, event)


        end

        % Value changed function: PminkPaEditField
        function PminkPaEditFieldValueChanged(app, event)

        end

        % Value changed function: PratioEditField
        function PratioEditFieldValueChanged(app, event)

        end

        % Value changed function: NetWorkMWEditField
        function NetWorkMWEditFieldValueChanged(app, event)
   
        
        end

        % Value changed function: RecupeffEditField
        function RecupeffEditFieldValueChanged(app, event)

        end

        % Value changed function: PreheatEditField
        function PreheatEditFieldValueChanged(app, event)
     
        
        end

        % Value changed function: PlotsensstudyCheckBox
        function PlotsensstudyCheckBoxValueChanged(app, event)


        end

        % Value changed function: TsDiagramCheckBox
        function TsDiagramCheckBoxValueChanged(app, event)


        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
%% Assigning all required vars from UI


if isfile("BOP_Inputs.mat") == 1
    delete "BOP_Inputs.mat"
end

if isfile("BOP_SensStudy.mat") == 1
    delete "BOP_SensStudy.mat"
end

if isfile("BOP_Optimize.mat") == 1
    delete "BOP_Optimize.mat"
end

CycleArray = [(app.ReheatButton.Value) (app.RecuperationButton.Value) (app.RecompressionButton.Value)];

% Inputs are stored as (Tmin, Tmax, Pmin, Pratio,
% RecompFrac,iseneffturb,iseneffcomp,iseneffrcomp,UA,UAsplit, NetWork, Recupeff, Preheat, cLTR, cHTR, Qin)

BOP_Inputs.Parameters(1) = app.TminKEditField.Value;
BOP_Inputs.Parameters(2) = app.TmaxKEditField.Value;
BOP_Inputs.Parameters(3) = app.PminkPaEditField.Value;
BOP_Inputs.Parameters(4) = app.PratioEditField.Value;
BOP_Inputs.Parameters(5) = app.RecompFractionEditField.Value;
BOP_Inputs.Parameters(6) = app.TurbeffEditField.Value;
BOP_Inputs.Parameters(7) = app.CompeffEditField.Value;
BOP_Inputs.Parameters(8) = app.RcompeffEditField.Value;
BOP_Inputs.Parameters(9) = app.UAEditField.Value;
BOP_Inputs.Parameters(10) = app.UAsplitEditField.Value;
BOP_Inputs.Parameters(11) = app.NetWorkMWEditField.Value;
BOP_Inputs.Parameters(12) = app.RecupeffEditField.Value;
BOP_Inputs.Parameters(13) = app.PreheatEditField.Value;
BOP_Inputs.Parameters(14) = app.CLTREditField.Value;
BOP_Inputs.Parameters(15) = app.CHTREditField.Value;
BOP_Inputs.Parameters(16) = app.QinMWEditField.Value;

if sum(CycleArray) ~= 0
    BOP_Inputs.CycleType = (find(CycleArray,1))+1;
else 
    BOP_Inputs.CycleType = 1;
end
BOP_Inputs.PLOT = app.PlotsensstudyCheckBox.Value;
BOP_Inputs.TS = app.TsDiagramCheckBox.Value;
BOP_Inputs.Recup = app.RecuperationButton.Value;
BOP_Inputs.Reheat = app.ReheatButton.Value;
BOP_Inputs.Recomp = app.RecompressionButton.Value;

BOP_Inputs.SolveType = app.SolveTypeEditField.Value;
BOP_Inputs.Dyreby = app.DyrebyRecompCheckBox.Value;


BOP_SensStudy.sensrange = app.RangeEditField.Value;
BOP_SensStudy.sensrange2 = app.RangeEditField_2.Value;
BOP_SensStudy.sensVarMin(1) = app.MinValueEditField.Value;
BOP_SensStudy.sensVarMin(2) = app.MinValueEditField_2.Value;
BOP_SensStudy.MultiVarApp = (app.MultiVarButton.Value);
BOP_SensStudy.sensvar = (app.Variable1DropDown.Value);
BOP_SensStudy.Resolution = app.ResolutionEditField.Value;
BOP_SensStudy.Resolution2 = app.ResolutionEditField_2.Value;
BOP_SensStudy.multivar = (app.SecondVarDropdown.Value);

LBounds = [app.TminBoundsEditField.Value app.TmaxBoundsEditField.Value app.PminBoundsEditField.Value ...
           app.PratioBoundsEditField.Value app.RecompFractionBoundsEditField.Value app.UABoundsEditField.Value ...
           app.UAsplitBoundsEditField.Value app.NetWorkBoundsEditField.Value app.RecupeffBoundsEditField.Value ...
           app.PreheatBoundsEditField.Value app.CLTRBoundsEditField.Value app.CHTRBoundsEditField.Value];

UBounds = [app.TminBoundsEditField_2.Value app.TmaxBoundsEditField_2.Value app.PminBoundsEditField_2.Value...
           app.PratioBoundsEditField_2.Value app.RecompFractionBoundsEditField_2.Value app.UABoundsEditField_2.Value ...
           app.UAsplitBoundsEditField_2.Value app.NetWorkBoundsEditField_2.Value app.RecupeffBoundsEditField_2.Value ...
           app.PreheatBoundsEditField_2.Value app.CLTRBoundsEditField_2.Value app.CHTRBoundsEditField_2.Value];

OptimizeParameters = [app.TminCheckBox.Value app.TmaxCheckBox.Value app.PminCheckBox.Value app.PratioCheckBox.Value ...
                      app.RecompFractionCheckBox.Value app.UACheckBox.Value app.UAsplitCheckBox.Value app.NetWorkBoundsEditField.Value ...
                      app.RecompFractionCheckBox.Value app.RecupeffCheckBox.Value app.PReheatCheckBox.Value app.CLTRCheckBox.Value ...
                      app.CHTRCheckBox.Value];

for n = 1:length(LBounds)
BOP_Optimize.LBounds(n) = LBounds(n);
BOP_Optimize.UBounds(n) = UBounds(n);
BOP_Optimize.Parameters(n) = OptimizeParameters(n);
end


BOP_Optimize.GenerationSize = app.GenerationSizeEditField.Value;
BOP_Optimize.MaxGenerations = app.MaxGenerationsEditField.Value;


save("BOP_Inputs.mat","BOP_Inputs");
save("BOP_SensStudy.mat","BOP_SensStudy");
save("BOP_Optimize.mat","BOP_Optimize");


BOP_Main;


%         % %  Ts Diagram

%%  
%% CURRENTLY NOT WORKING 
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

%         if app.TsDiagramCheckBox.Value == 1
% 
%             if Recomp == 1
%                 tsvecx1 = [s1(4) s2(4)  s3(4) s4(4) s5(4) s6(4) s7(4) s8(4) s9(4) s1(4)];
%                 tsvecy1 = [s1(1) s2(1)  s3(1) s4(1) s5(1)  s6(1) s7(1) s8(1) s9(1) s1(4)];
% 
%                 tsvecx2 = [s9(4) s10(4)   s4(4) s5(4) s6(4) s7(4) s8(4) s9(4)];
%                 tsvecy2 = [s9(1) s10(1)   s4(1) s5(1)  s6(1) s7(1) s8(1) s9(1)];
% 
%             else
%                 if  (Regen && Reheat) == 1
%                     tsvecx = [s1(4) s2(4) s5(4) s3(4) sA(4) sB(4) s4(4) s6(4) s1(4)];
%                     tsvecy = [s1(1) s2(1) s5(1) s3(1) sA(1) sB(1) s4(1) s6(1) s1(1)];
% 
%                 elseif Reheat == 1 && Regen == 0
%                     tsvecx  = [s1(4) s2(4) s3(4) sA(4) sB(4) s4(4) s1(4)];
%                     tsvecy = [s1(1) s2(1) s3(1) sA(1) sB(1) s4(1) s1(1)];
%                 elseif Regen == 1 && Reheat == 0
%                     tsvecx = [s1(4) s2(4) s5(4) s3(4) s4(4) s6(4) s1(4)];
%                     tsvecy = [s1(1) s2(1) s5(1) s3(1) s4(1) s6(1) s1(1)];
%                 elseif (Reheat && Regen) == 0
%                     tsvecx = [s1(4) s2(4) s3(4) s4(4) s1(4)];
%                     tsvecy = [ s1(1) s2(1) s3(1) s4(1) s1(1)];
% 
%                 end
%             end
% 
%             if Recomp == 0
% 
%                 plot(tsvecx,tsvecy);
%                 app.UIAxes.XGrid = "on";
%                 app.UIAxes.YGrid = "on";
%                 app.UIAxes.YLim = ([(s1(1)-20) (s3(1)+40)]);
%             else
% 
%                 app.UIAxes.YLim = ([(s1(1)-20) (s6(1)+40)]);
%             end
% 
%         end
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%


% OPENS TEXT DUMP FILE

        end

        % Value changed function: TmaxKEditField
        function TmaxKEditFieldValueChanged(app, event)
 
        end

        % Value changed function: SingleVarButton
        function SingleVarButtonValueChanged(app, event)
           
            if value == 1
                Runsingle = 1;
                app.Variable1DropDown.Visible ="on";
                app.VariableLabel.Visible = "on";
                app.MinValueEditField.Visible = "on";
                app.MinValueEditFieldLabel.Visible= "on";
                app.RangeEditFieldLabel.Visible = "on";
                app.RangeEditField.Visible = "on";
                app.ResolutionEditField.Visible = "on";
                app.ResolutionEditFieldLabel.Visible= "on";
                app.StartButton.Visible = "on";
                app.MultiVarButton.Value = 0;

                app.SecondVarDropdown.Visible ="off";
                app.Variable2Label.Visible = "off";
                app.MinValueEditField_2.Visible = "off";
                app.MinValueEditField_2Label.Visible= "off";
                app.RangeEditField_2Label.Visible = "off";
                app.RangeEditField_2.Visible = "off";
                app.ResolutionEditField_2.Visible = "off";
                app.ResolutionEditField_2Label.Visible= "off";
               
                           
            elseif value == 0 && app.MultiVarButton.Value == 0
                
                app.Variable1DropDown.Visible ="off";
                app.VariableLabel.Visible = "off";
                app.MinValueEditField.Visible = "off";
                app.MinValueEditFieldLabel.Visible= "off";
                app.RangeEditFieldLabel.Visible = "off";
                app.RangeEditField.Visible = "off";
                app.ResolutionEditField.Visible = "off";
                app.ResolutionEditFieldLabel.Visible= "off";
                app.StartButton.Visible = "off";
            end
                
        end

        % Value changed function: MultiVarButton
        function MultiVarButtonValueChanged(app, event)
            value = app.MultiVarButton.Value;
                         
            if value == 1
                
                app.Variable1DropDown.Visible ="on";
                app.VariableLabel.Visible = "on";
                app.MinValueEditField.Visible = "on";
                app.MinValueEditFieldLabel.Visible= "on";
                app.RangeEditFieldLabel.Visible = "on";
                app.RangeEditField.Visible = "on";
                app.ResolutionEditField.Visible = "on";
                app.ResolutionEditFieldLabel.Visible= "on";
          
                app.SecondVarDropdown.Visible ="on";
                app.Variable2Label.Visible = "on";
                app.MinValueEditField_2.Visible = "on";
                app.MinValueEditField_2Label.Visible= "on";
                app.RangeEditField_2Label.Visible = "on";
                app.RangeEditField_2.Visible = "on";
                app.ResolutionEditField_2.Visible = "on";
                app.ResolutionEditField_2Label.Visible= "on";
                app.StartButton.Visible = "on";

                app.SingleVarButton.Value = 0;
            else
                
                 app.Variable1DropDown.Visible ="off";
                app.VariableLabel.Visible = "off";
                app.MinValueEditField.Visible = "off";
                app.MinValueEditFieldLabel.Visible= "off";
                app.RangeEditFieldLabel.Visible = "off";
                app.RangeEditField.Visible = "off";
                app.ResolutionEditField.Visible = "off";
                app.ResolutionEditFieldLabel.Visible= "off";
            
                app.SecondVarDropdown.Visible ="off";
                app.Variable2Label.Visible = "off";
                app.MinValueEditField_2.Visible = "off";
                app.MinValueEditField_2Label.Visible= "off";
                app.RangeEditField_2Label.Visible = "off";
                app.RangeEditField_2.Visible = "off";
                app.ResolutionEditField_2.Visible = "off";
                app.ResolutionEditField_2Label.Visible= "off";
                app.StartButton.Visible = "off";
            end

        end

        % Value changed function: RecuperationButton
        function RecuperationButtonValueChanged(app, event)
      app.ReheatButton.Value = 0;
      app.RecompressionButton.Value =  0;
      app.DyrebyRecompCheckBox.Enable = 0;

                app.UAEditField.Enable = 0;
                app.UAsplitEditField.Enable = 0;
                app.CLTREditField.Enable = 0;
                app.CHTREditField.Enable = 0;
                app.QinMWEditField.Enable = 0;
                app.RcompeffEditField.Enable = 0;
                app.RecompFractionEditField.Enable = 0;

        switch app.RecuperationButton.Value == 1

            case 0

                app.RecupeffEditField.Enable = 0;

            case 1

          
            app.RecupeffEditField.Enable = 1;
           
        end

               app.UAEditField.Enable = 0;
                app.UAsplitEditField.Enable = 0;
                app.CLTREditField.Enable = 0;
                app.CHTREditField.Enable = 0;
                app.QinMWEditField.Enable = 0;
                app.RcompeffEditField.Enable = 0;
                app.RecompFractionEditField.Enable = 0;
                app.PreheatEditField.Enable = 0;
                app.NetWorkMWEditField.Enable = 1;
            
        end

        % Value changed function: RecompressionButton
        function RecompressionButtonValueChanged(app, event)
              switch app.RecompressionButton.Value 
                  case 0
                       app.DyrebyRecompCheckBox.Enable = 0;
             
                  case 1
                       app.DyrebyRecompCheckBox.Enable = 1;
               app.ReheatButton.Value = 0;
               app.RecuperationButton.Value = 0;
              end

                 switch app.DyrebyRecompCheckBox.Value

             case 0
                  if app.RecompressionButton.Value == 1
                     app.QinMWEditField.Enable = 1;
                     app.CLTREditField.Enable = 1;
                     app.CHTREditField.Enable = 1;
                     app.UAEditField.Enable = 0;
                     app.UAsplitEditField.Enable = 0;
                     app.NetWorkMWEditField.Enable = 0;


                 else
                     app.QinMWEditField.Enable = 0;
                     app.CLTREditField.Enable = 0;
                     app.CHTREditField.Enable = 0;
                     app.UAEditField.Enable = 1;
                     app.UAsplitEditField.Enable = 1;
                     app.NetWorkMWEditField.Enable = 1;
                     
                 end


             case 1

                 
                  if app.RecompressionButton.Value == 1
                     app.QinMWEditField.Enable = 0;
                     app.CLTREditField.Enable = 0;
                     app.CHTREditField.Enable = 0;
                     app.UAEditField.Enable = 1;
                     app.UAsplitEditField.Enable = 1;
                     app.NetWorkMWEditField.Enable = 1;


                 else
                app.UAEditField.Enable = 0;
                app.UAsplitEditField.Enable = 0;
                app.CLTREditField.Enable = 0;
                app.CHTREditField.Enable = 0;
                app.QinMWEditField.Enable = 0;
                app.RcompeffEditField.Enable = 0;
                app.RecompFractionEditField.Enable = 0;
                app.NetWorkMWEditField.Enable = 1;
                     
                 end


         end

        switch app.RecompressionButton.Value
            case 0
                app.UAEditField.Enable = 0;
                app.UAsplitEditField.Enable = 0;
                app.CLTREditField.Enable = 0;
                app.CHTREditField.Enable = 0;
                app.QinMWEditField.Enable = 0;
                app.RcompeffEditField.Enable = 0;
                app.RecompFractionEditField.Enable = 0;

            case 1

                app.RcompeffEditField.Enable = 1;
                app.RecompFractionEditField.Enable = 1;

        end

        app.PreheatEditField.Enable = 0;
        app.RecupeffEditField.Enable = 0;
        end

        % Value changed function: ReheatButton
        function ReheatButtonValueChanged(app, event)
           app.RecompressionButton.Value = 0;
           app.RecuperationButton.Value = 0;
           app.DyrebyRecompCheckBox.Enable = 0;

        switch app.ReheatButton.Value
            case 0
                app.PreheatEditField.Enable = 0;
            case 1
                app.PreheatEditField.Enable = 1;

        end

               app.UAEditField.Enable = 0;
                app.UAsplitEditField.Enable = 0;
                app.CLTREditField.Enable = 0;
                app.CHTREditField.Enable = 0;
                app.QinMWEditField.Enable = 0;
                app.RcompeffEditField.Enable = 0;
                app.RecompFractionEditField.Enable = 0;
                app.RecupeffEditField.Enable = 0;
                app.NetWorkMWEditField.Enable = 1;
        end

        % Button pushed function: DefaultButton
        function DefaultButtonPushed(app, event)
            app.TmaxKEditField.Value = 838.15;
            app.TminKEditField.Value = 308.15;
            app.PminkPaEditField.Value = 7400;
            app.PratioEditField.Value = 3;
            app.NetWorkMWEditField.Value = 5;

            app.RecompFractionEditField.Value = 0.6;
            app.RecupeffEditField.Value = 1;
            app.UAEditField.Value = 8E3;
            app.UAsplitEditField.Value = 0.6;
            app.PreheatEditField.Value = 0.5;
            app.TurbeffEditField.Value = 0.85;
            app.CompeffEditField.Value = 0.82;
            app.RcompeffEditField.Value = 0.82;
        end

        % Drop down opening function: Variable1DropDown
        function Variable1DropDownOpening(app, event)
            
        end

        % Value changed function: SecondVarDropdown
        function SecondVarDropdownValueChanged(app, event)
            value = app.SecondVarDropdown.Value;
            app.SecondVarDropdown.ItemsData = 1:numel(app.SecondVarDropdown.Items);
        end

        % Value changed function: DyrebyRecompCheckBox
        function DyrebyRecompCheckBoxValueChanged(app, event)
       
                switch app.DyrebyRecompCheckBox.Value

             case 0
                  if app.RecompressionButton.Value == 1
                     app.QinMWEditField.Enable = 1;
                     app.CLTREditField.Enable = 1;
                     app.CHTREditField.Enable = 1;
                     app.UAEditField.Enable = 0;
                     app.UAsplitEditField.Enable = 0;
                     app.NetWorkMWEditField.Enable = 0;


                 else
                     app.QinMWEditField.Enable = 0;
                     app.CLTREditField.Enable = 0;
                     app.CHTREditField.Enable = 0;
                     app.UAEditField.Enable = 1;
                     app.UAsplitEditField.Enable = 1;
                     app.NetWorkMWEditField.Enable = 1;
                     
                 end


             case 1
                  if app.RecompressionButton.Value == 1
                     app.QinMWEditField.Enable = 0;
                     app.CLTREditField.Enable = 0;
                     app.CHTREditField.Enable = 0;
                     app.UAEditField.Enable = 1;
                     app.UAsplitEditField.Enable = 1;
                     app.NetWorkMWEditField.Enable = 1;


                 else
                app.UAEditField.Enable = 0;
                app.UAsplitEditField.Enable = 0;
                app.CLTREditField.Enable = 0;
                app.CHTREditField.Enable = 0;
                app.QinMWEditField.Enable = 0;
                app.RcompeffEditField.Enable = 0;
                app.RecompFractionEditField.Enable = 0;
                app.NetWorkMWEditField.Enable = 1;
                     
                 end


         end

        end

        % Value changed function: RcompeffEditField
        function RcompeffEditFieldValueChanged(app, event)
         
        end

        % Value changed function: MinValueEditField
        function MinValueEditFieldValueChanged(app, event)
        
        end

        % Value changed function: TurbeffEditField
        function TurbeffEditFieldValueChanged(app, event)
            
        end

        % Value changed function: RecompFractionEditField
        function RecompFractionEditFieldValueChanged(app, event)
            
        end

        % Value changed function: UAEditField
        function UAEditFieldValueChanged(app, event)
           
        end

        % Value changed function: UAsplitEditField
        function UAsplitEditFieldValueChanged(app, event)
           
        end

        % Value changed function: CLTREditField
        function CLTREditFieldValueChanged(app, event)

        end

        % Value changed function: CHTREditField
        function CHTREditFieldValueChanged(app, event)
         
        end

        % Button down function: OptimizeTab
        function OptimizeTabButtonDown(app, event)
        app.CHTRBoundsEditField.Value = app.CHTREditField.Value;
        app.CHTRBoundsEditField_2.Value = app.CHTREditField.Value;

        app.CLTRBoundsEditField.Value = app.CLTREditField.Value;
        app.CLTRBoundsEditField_2.Value = app.CLTREditField.Value;

        app.UAsplitBoundsEditField.Value = app.UAsplitEditField.Value;
        app.UAsplitBoundsEditField_2.Value = app.UAsplitEditField.Value;

        app.UABoundsEditField.Value = app.UAEditField.Value;
        app.UABoundsEditField_2.Value = app.UAEditField.Value;

        app.RecompFractionBoundsEditField.Value = app.RecompFractionEditField.Value;
        app.RecompFractionBoundsEditField_2.Value = app.RecompFractionEditField.Value;

        app.TmaxBoundsEditField.Value = app.TmaxKEditField.Value;
        app.TmaxBoundsEditField_2.Value = app.TmaxKEditField.Value;

        app.TminBoundsEditField.Value = app.TminKEditField.Value;
        app.TminBoundsEditField_2.Value = app.TminKEditField.Value;

        app.PminBoundsEditField.Value = app.PminkPaEditField.Value;
        app.PminBoundsEditField_2.Value = app.PminkPaEditField.Value;

        app.PratioBoundsEditField.Value = app.PratioEditField.Value;
        app.PratioBoundsEditField_2.Value = app.PratioEditField.Value;

        app.NetWorkBoundsEditField.Value = app.NetWorkMWEditField.Value;
        app.NetWorkBoundsEditField_2.Value = app.NetWorkMWEditField.Value;

        app.RecupeffBoundsEditField.Value = app.RecupeffEditField.Value;
        app.RecupeffBoundsEditField_2.Value = app.RecupeffEditField.Value;

        app.PreheatBoundsEditField.Value = app.PreheatEditField.Value;
        app.PreheatBoundsEditField_2.Value = app.PreheatEditField.Value;


        end

        % Value changed function: TminCheckBox
        function TminCheckBoxValueChanged(app, event)
        switch app.TminCheckBox.Value
            case 0 
                app.TminBoundsEditField.Enable = 0;
                app.TminBoundsEditField_2.Enable = 0;

            case 1
                app.TminBoundsEditField.Enable = 1;
                app.TminBoundsEditField_2.Enable = 1;
            
        end
        end

        % Value changed function: TmaxCheckBox
        function TmaxCheckBoxValueChanged(app, event)
            switch app.TmaxCheckBox.Value
            case 0 
                app.TmaxBoundsEditField.Enable = 0;
                app.TmaxBoundsEditField_2.Enable = 0;

            case 1
                app.TmaxBoundsEditField.Enable = 1;
                app.TmaxBoundsEditField_2.Enable = 1;
            
        end
        end

        % Value changed function: PminCheckBox
        function PminCheckBoxValueChanged(app, event)
           switch app.TminCheckBox.Value
            case 0 
                app.PminBoundsEditField.Enable = 0;
                app.PminBoundsEditField_2.Enable = 0;

            case 1
                app.PminBoundsEditField.Enable = 1;
                app.PminBoundsEditField_2.Enable = 1;
            
        end
        end

        % Value changed function: PratioCheckBox
        function PratioCheckBoxValueChanged(app, event)
             switch app.PratioCheckBox.Value

            case 0 
                app.PratioBoundsEditField.Enable = 0;
                app.PratioBoundsEditField_2.Enable = 0;

            case 1
                app.PratioBoundsEditField.Enable = 1;
                app.PratioBoundsEditField_2.Enable = 1;
            
        end
        end

        % Value changed function: RecompFractionCheckBox
        function RecompFractionCheckBoxValueChanged(app, event)
             switch app.RecompFractionCheckBox.Value
            case 0 
                app.RecompFractionBoundsEditField.Enable = 0;
                app.RecompFractionBoundsEditField_2.Enable = 0;

            case 1
                app.RecompFractionBoundsEditField.Enable = 1;
                app.RecompFractionBoundsEditField_2.Enable = 1;
            
        end
        end

        % Value changed function: UACheckBox
        function UACheckBoxValueChanged(app, event)
            switch app.UACheckBox.Value
            case 0 
                app.UABoundsEditField.Enable = 0;
                app.UABoundsEditField_2.Enable = 0;

            case 1
                app.UABoundsEditField.Enable = 1;
                app.UABoundsEditField_2.Enable = 1;
            
        end
        end

        % Value changed function: UAsplitCheckBox
        function UAsplitCheckBoxValueChanged(app, event)
             switch app.UAsplitCheckBox.Value
            case 0 
                app.UAsplitBoundsEditField.Enable = 0;
                app.UAsplitBoundsEditField_2.Enable = 0;

            case 1
                app.UAsplitBoundsEditField.Enable = 1;
                app.UAsplitBoundsEditField_2.Enable = 1;
            
        end
            
        end

        % Value changed function: NetWorkCheckBox
        function NetWorkCheckBoxValueChanged(app, event)
             switch app.NetWorkCheckBox.Value
            case 0 
                app.NetWorkBoundsEditField.Enable = 0;
                app.NetWorkBoundsEditField_2.Enable = 0;

            case 1
                app.NetWorkBoundsEditField.Enable = 1;
                app.NetWorkBoundsEditField_2.Enable = 1;
            
        end
        end

        % Value changed function: RecupeffCheckBox
        function RecupeffCheckBoxValueChanged(app, event)
             switch app.RecupeffCheckBox.Value
            case 0 
                app.RecupeffBoundsEditField.Enable = 0;
                app.RecupeffBoundsEditField_2.Enable = 0;

            case 1
                app.RecupeffBoundsEditField.Enable = 1;
                app.RecupeffBoundsEditField_2.Enable = 1;
            
        end
        end

        % Value changed function: PReheatCheckBox
        function PReheatCheckBoxValueChanged(app, event)
             switch app.PReheatCheckBox.Value
            case 0 
                app.PreheatBoundsEditField.Enable = 0;
                app.PreheatBoundsEditField_2.Enable = 0;

            case 1
                app.PreheatBoundsEditField.Enable = 1;
                app.PreheatBoundsEditField_2.Enable = 1;
            
        end
        end

        % Value changed function: CLTRCheckBox
        function CLTRCheckBoxValueChanged(app, event)
             switch app.CLTRCheckBox.Value
            case 0 
                app.CLTRBoundsEditField.Enable = 0;
                app.CLTRBoundsEditField_2.Enable = 0;

            case 1
                app.CLTRBoundsEditField.Enable = 1;
                app.CLTRBoundsEditField_2.Enable = 1;
            
        end
        end

        % Value changed function: CHTRCheckBox
        function CHTRCheckBoxValueChanged(app, event)
             switch app.CHTRCheckBox.Value
            case 0 
                app.CHTRBoundsEditField.Enable = 0;
                app.CHTRBoundsEditField_2.Enable = 0;

            case 1
                app.CHTRBoundsEditField.Enable = 1;
                app.CHTRBoundsEditField_2.Enable = 1;
            
        end
        end

        % Button pushed function: StartButton_2
        function StartButton_2Pushed(app, event)
        app.SolveTypeEditField.Value = 3; %% Optimize
        StartButtonPushed(app,event)
        end

        % Callback function
        function SimpleButtonValueChanged(app, event)
        CycleType = 3;
        end

        % Button pushed function: SingleSolveButton
        function SingleSolveButtonPushed(app, event)
           app.SolveTypeEditField.Value = 1; %% Single Solve
        StartButtonPushed(app,event)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.HomeTab = uitab(app.TabGroup);
            app.HomeTab.Title = 'Home';
            app.BOPBraytonOptimizationProgramLabel = uilabel(app.HomeTab);
            app.BOPBraytonOptimizationProgramLabel.Text = 'BOP - Brayton Optimization Program';
            app.Ver33Label = uilabel(app.HomeTab);
            app.Ver33Label.Text = 'Ver 3.3';
            app.WelcomeToBOPLabel = uilabel(app.HomeTab);
            app.WelcomeToBOPLabel.Text = 'Welcome To BOP';
            app.Image = uiimage(app.HomeTab);
            app.Image_2 = uiimage(app.HomeTab);
            app.CycleTab = uitab(app.TabGroup);
            app.CycleTab.Title = 'Cycle';
            app.RecuperationButton = uibutton(app.CycleTab, 'state');
            app.RecuperationButton.ValueChangedFcn = createCallbackFcn(app, @RecuperationButtonValueChanged, true);
            app.RecuperationButton.Text = 'Recuperation';
            app.ReheatButton = uibutton(app.CycleTab, 'state');
            app.ReheatButton.ValueChangedFcn = createCallbackFcn(app, @ReheatButtonValueChanged, true);
            app.ReheatButton.Text = 'Reheat';
            app.RecompressionButton = uibutton(app.CycleTab, 'state');
            app.RecompressionButton.ValueChangedFcn = createCallbackFcn(app, @RecompressionButtonValueChanged, true);
            app.RecompressionButton.Text = 'Recompression';
            app.CycleComponentsLabel_2 = uilabel(app.CycleTab);
            app.CycleComponentsLabel_2.Text = 'Cycle Components';
            app.DyrebyRecompCheckBox = uicheckbox(app.CycleTab);
            app.DyrebyRecompCheckBox.ValueChangedFcn = createCallbackFcn(app, @DyrebyRecompCheckBoxValueChanged, true);
            app.DyrebyRecompCheckBox.Enable = 'off';
            app.DyrebyRecompCheckBox.Text = 'Dyreby (Recomp)';
            app.DyrebyRecompCheckBox.Value = true;
            app.DefaultButton = uibutton(app.CycleTab, 'push');
            app.DefaultButton.ButtonPushedFcn = createCallbackFcn(app, @DefaultButtonPushed, true);
            app.DefaultButton.Text = 'Default';
            app.TminKEditFieldLabel = uilabel(app.CycleTab);
            app.TminKEditFieldLabel.Text = 'Tmin (K)';
            app.TminKEditField = uieditfield(app.CycleTab, 'numeric');
            app.TminKEditField.ValueChangedFcn = createCallbackFcn(app, @TminKEditFieldValueChanged, true);
            app.NetWorkMWEditFieldLabel = uilabel(app.CycleTab);
            app.NetWorkMWEditFieldLabel.Text = 'Net Work MW';
            app.NetWorkMWEditField = uieditfield(app.CycleTab, 'numeric');
            app.NetWorkMWEditField.ValueChangedFcn = createCallbackFcn(app, @NetWorkMWEditFieldValueChanged, true);
            app.TmaxKEditFieldLabel = uilabel(app.CycleTab);
            app.TmaxKEditFieldLabel.Text = 'Tmax (K)';
            app.TmaxKEditField = uieditfield(app.CycleTab, 'numeric');
            app.TmaxKEditField.ValueChangedFcn = createCallbackFcn(app, @TmaxKEditFieldValueChanged, true);
            app.PminkPaEditFieldLabel = uilabel(app.CycleTab);
            app.PminkPaEditFieldLabel.Text = 'Pmin (kPa)';
            app.PminkPaEditField = uieditfield(app.CycleTab, 'numeric');
            app.PminkPaEditField.ValueChangedFcn = createCallbackFcn(app, @PminkPaEditFieldValueChanged, true);
            app.PratioEditFieldLabel = uilabel(app.CycleTab);
            app.PratioEditFieldLabel.Text = 'Pratio';
            app.PratioEditField = uieditfield(app.CycleTab, 'numeric');
            app.PratioEditField.ValueChangedFcn = createCallbackFcn(app, @PratioEditFieldValueChanged, true);
            app.RecupeffEditFieldLabel = uilabel(app.CycleTab);
            app.RecupeffEditFieldLabel.Text = 'Recup eff';
            app.RecupeffEditField = uieditfield(app.CycleTab, 'numeric');
            app.RecupeffEditField.ValueChangedFcn = createCallbackFcn(app, @RecupeffEditFieldValueChanged, true);
            app.RecupeffEditField.Enable = 'off';
            app.TurbeffEditFieldLabel = uilabel(app.CycleTab);
            app.TurbeffEditFieldLabel.Text = 'Turb eff';
            app.TurbeffEditField = uieditfield(app.CycleTab, 'numeric');
            app.TurbeffEditField.ValueChangedFcn = createCallbackFcn(app, @TurbeffEditFieldValueChanged, true);
            app.CompeffEditFieldLabel = uilabel(app.CycleTab);
            app.CompeffEditFieldLabel.Text = 'Comp eff';
            app.CompeffEditField = uieditfield(app.CycleTab, 'numeric');
            app.RcompeffEditFieldLabel = uilabel(app.CycleTab);
            app.RcompeffEditFieldLabel.Text = 'Rcomp eff';
            app.RcompeffEditField = uieditfield(app.CycleTab, 'numeric');
            app.RcompeffEditField.ValueChangedFcn = createCallbackFcn(app, @RcompeffEditFieldValueChanged, true);
            app.RcompeffEditField.Enable = 'off';
            app.RecompFractionEditFieldLabel = uilabel(app.CycleTab);
            app.RecompFractionEditFieldLabel.Text = 'Recomp Fraction';
            app.RecompFractionEditField = uieditfield(app.CycleTab, 'numeric');
            app.RecompFractionEditField.ValueChangedFcn = createCallbackFcn(app, @RecompFractionEditFieldValueChanged, true);
            app.RecompFractionEditField.Enable = 'off';
            app.UAEditFieldLabel = uilabel(app.CycleTab);
            app.UAEditFieldLabel.Text = 'UA';
            app.UAEditField = uieditfield(app.CycleTab, 'numeric');
            app.UAEditField.ValueChangedFcn = createCallbackFcn(app, @UAEditFieldValueChanged, true);
            app.UAEditField.Enable = 'off';
            app.UAsplitEditFieldLabel = uilabel(app.CycleTab);
            app.UAsplitEditFieldLabel.Text = 'UA split';
            app.UAsplitEditField = uieditfield(app.CycleTab, 'numeric');
            app.UAsplitEditField.ValueChangedFcn = createCallbackFcn(app, @UAsplitEditFieldValueChanged, true);
            app.UAsplitEditField.Enable = 'off';
            app.PreheatEditFieldLabel = uilabel(app.CycleTab);
            app.PreheatEditFieldLabel.Text = 'Preheat';
            app.PreheatEditField = uieditfield(app.CycleTab, 'numeric');
            app.PreheatEditField.ValueChangedFcn = createCallbackFcn(app, @PreheatEditFieldValueChanged, true);
            app.PreheatEditField.Enable = 'off';
            app.CLTREditFieldLabel = uilabel(app.CycleTab);
            app.CLTREditFieldLabel.Text = 'C LTR';
            app.CLTREditField = uieditfield(app.CycleTab, 'numeric');
            app.CLTREditField.ValueChangedFcn = createCallbackFcn(app, @CLTREditFieldValueChanged, true);
            app.CLTREditField.Enable = 'off';
            app.CHTREditFieldLabel = uilabel(app.CycleTab);
            app.CHTREditFieldLabel.Text = 'C HTR';
            app.CHTREditField = uieditfield(app.CycleTab, 'numeric');
            app.CHTREditField.ValueChangedFcn = createCallbackFcn(app, @CHTREditFieldValueChanged, true);
            app.CHTREditField.Enable = 'off';
            app.SingleSolveButton = uibutton(app.CycleTab, 'push');
            app.SingleSolveButton.ButtonPushedFcn = createCallbackFcn(app, @SingleSolveButtonPushed, true);
            app.SingleSolveButton.Text = 'Single Solve';
            app.QinMWEditFieldLabel = uilabel(app.CycleTab);
            app.QinMWEditFieldLabel.Text = 'Qin MW';
            app.QinMWEditField = uieditfield(app.CycleTab, 'numeric');
            app.QinMWEditField.Enable = 'off';
            app.SensitivityStudyTab = uitab(app.TabGroup);
            app.SensitivityStudyTab.Title = 'Sensitivity Study';
            app.SingleVarButton = uibutton(app.SensitivityStudyTab, 'state');
            app.SingleVarButton.ValueChangedFcn = createCallbackFcn(app, @SingleVarButtonValueChanged, true);
            app.SingleVarButton.Text = 'Single Var';
            app.MultiVarButton = uibutton(app.SensitivityStudyTab, 'state');
            app.MultiVarButton.ValueChangedFcn = createCallbackFcn(app, @MultiVarButtonValueChanged, true);
            app.MultiVarButton.Text = 'Multi Var';
            app.StartButton = uibutton(app.SensitivityStudyTab, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Visible = 'off';
            app.StartButton.Text = 'Start';
            app.SecondVarDropdown = uidropdown(app.SensitivityStudyTab);
            app.SecondVarDropdown.Items = {'T max', 'T min', 'P min', 'P ratio', 'Regen eff', 'Net Work', 'P ratio reheat', 'Turb eff', 'Comp eff'};
            app.SecondVarDropdown.ItemsData = [1 2 3 4 5 6 7 8 9 10];
            app.SecondVarDropdown.ValueChangedFcn = createCallbackFcn(app, @SecondVarDropdownValueChanged, true);
            app.SecondVarDropdown.Visible = 'off';
            app.SecondVarDropdown.Value = 2;
            app.Variable2Label = uilabel(app.SensitivityStudyTab);
            app.Variable2Label.Visible = 'off';
            app.Variable2Label.Text = 'Variable  2';
            app.MinValueEditFieldLabel = uilabel(app.SensitivityStudyTab);
            app.MinValueEditFieldLabel.Visible = 'off';
            app.MinValueEditFieldLabel.Text = 'Min Value';
            app.MinValueEditField = uieditfield(app.SensitivityStudyTab, 'numeric');
            app.MinValueEditField.ValueChangedFcn = createCallbackFcn(app, @MinValueEditFieldValueChanged, true);
            app.MinValueEditField.Visible = 'off';
            app.MinValueEditField.Value = 850;
            app.MinValueEditField_2Label = uilabel(app.SensitivityStudyTab);
            app.MinValueEditField_2Label.Visible = 'off';
            app.MinValueEditField_2Label.Text = 'Min Value';
            app.MinValueEditField_2 = uieditfield(app.SensitivityStudyTab, 'numeric');
            app.MinValueEditField_2.Visible = 'off';
            app.MinValueEditField_2.Value = 1;
            app.ResolutionEditFieldLabel = uilabel(app.SensitivityStudyTab);
            app.ResolutionEditFieldLabel.Visible = 'off';
            app.ResolutionEditFieldLabel.Text = 'Resolution';
            app.ResolutionEditField = uieditfield(app.SensitivityStudyTab, 'numeric');
            app.ResolutionEditField.Visible = 'off';
            app.ResolutionEditField.Value = 1;
            app.ResolutionEditField_2Label = uilabel(app.SensitivityStudyTab);
            app.ResolutionEditField_2Label.Visible = 'off';
            app.ResolutionEditField_2Label.Text = 'Resolution';
            app.ResolutionEditField_2 = uieditfield(app.SensitivityStudyTab, 'numeric');
            app.ResolutionEditField_2.Visible = 'off';
            app.ResolutionEditField_2.Value = 1;
            app.RangeEditFieldLabel = uilabel(app.SensitivityStudyTab);
            app.RangeEditFieldLabel.Visible = 'off';
            app.RangeEditFieldLabel.Text = 'Range';
            app.RangeEditField = uieditfield(app.SensitivityStudyTab, 'numeric');
            app.RangeEditField.Visible = 'off';
            app.RangeEditField.Value = 1;
            app.RangeEditField_2Label = uilabel(app.SensitivityStudyTab);
            app.RangeEditField_2Label.Visible = 'off';
            app.RangeEditField_2Label.Text = 'Range';
            app.RangeEditField_2 = uieditfield(app.SensitivityStudyTab, 'numeric');
            app.RangeEditField_2.Visible = 'off';
            app.RangeEditField_2.Value = 1;
            app.Variable1DropDown = uidropdown(app.SensitivityStudyTab);
            app.Variable1DropDown.Items = {'T max', 'T min', 'P min', 'P ratio', 'Regen eff', 'Net Work', 'P ratio reheat', 'Turbine eff', 'Comp eff'};
            app.Variable1DropDown.ItemsData = [1 2 3 4 5 6 7 8 9 10];
            app.Variable1DropDown.DropDownOpeningFcn = createCallbackFcn(app, @Variable1DropDownOpening, true);
            app.Variable1DropDown.ValueChangedFcn = createCallbackFcn(app, @Variable1DropDownValueChanged, true);
            app.Variable1DropDown.Visible = 'off';
            app.Variable1DropDown.Value = 1;
            app.VariableLabel = uilabel(app.SensitivityStudyTab);
            app.VariableLabel.Visible = 'off';
            app.VariableLabel.Text = 'Variable   1';
            app.SensitivityStudySolverLabel = uilabel(app.SensitivityStudyTab);
            app.SensitivityStudySolverLabel.Text = 'Sensitivity Study Solver';
            app.OptimizeTab = uitab(app.TabGroup);
            app.OptimizeTab.Title = 'Optimize';
            app.OptimizeTab.ButtonDownFcn = createCallbackFcn(app, @OptimizeTabButtonDown, true);
            app.OptimizerGeneticAlgorithmLabel = uilabel(app.OptimizeTab);
            app.OptimizerGeneticAlgorithmLabel.Text = 'Optimizer -  Genetic Algorithm';
            app.GenerationSizeEditFieldLabel = uilabel(app.OptimizeTab);
            app.GenerationSizeEditFieldLabel.Text = 'Generation Size';
            app.GenerationSizeEditField = uieditfield(app.OptimizeTab, 'numeric');
            app.MaxGenerationsEditFieldLabel = uilabel(app.OptimizeTab);
            app.MaxGenerationsEditFieldLabel.Text = 'Max Generations';
            app.MaxGenerationsEditField = uieditfield(app.OptimizeTab, 'numeric');
            app.TminCheckBox = uicheckbox(app.OptimizeTab);
            app.TminCheckBox.ValueChangedFcn = createCallbackFcn(app, @TminCheckBoxValueChanged, true);
            app.TminCheckBox.Text = 'Tmin';
            app.ParameterstooptimizeLabel = uilabel(app.OptimizeTab);
            app.ParameterstooptimizeLabel.Text = 'Parameters  to optimize';
            app.TmaxCheckBox = uicheckbox(app.OptimizeTab);
            app.TmaxCheckBox.ValueChangedFcn = createCallbackFcn(app, @TmaxCheckBoxValueChanged, true);
            app.TmaxCheckBox.Text = 'Tmax';
            app.PminCheckBox = uicheckbox(app.OptimizeTab);
            app.PminCheckBox.ValueChangedFcn = createCallbackFcn(app, @PminCheckBoxValueChanged, true);
            app.PminCheckBox.Text = 'Pmin';
            app.PratioCheckBox = uicheckbox(app.OptimizeTab);
            app.PratioCheckBox.ValueChangedFcn = createCallbackFcn(app, @PratioCheckBoxValueChanged, true);
            app.PratioCheckBox.Text = 'Pratio';
            app.RecompFractionCheckBox = uicheckbox(app.OptimizeTab);
            app.RecompFractionCheckBox.ValueChangedFcn = createCallbackFcn(app, @RecompFractionCheckBoxValueChanged, true);
            app.RecompFractionCheckBox.Text = 'Recomp Fraction';
            app.UACheckBox = uicheckbox(app.OptimizeTab);
            app.UACheckBox.ValueChangedFcn = createCallbackFcn(app, @UACheckBoxValueChanged, true);
            app.UACheckBox.Text = 'UA';
            app.UAsplitCheckBox = uicheckbox(app.OptimizeTab);
            app.UAsplitCheckBox.ValueChangedFcn = createCallbackFcn(app, @UAsplitCheckBoxValueChanged, true);
            app.UAsplitCheckBox.Text = 'UAsplit';
            app.TminBoundsEditFieldLabel = uilabel(app.OptimizeTab);
            app.TminBoundsEditFieldLabel.Text = 'Tmin Bounds';
            app.TminBoundsEditField = uieditfield(app.OptimizeTab, 'numeric');
            app.PminBoundsEditFieldLabel = uilabel(app.OptimizeTab);
            app.PminBoundsEditFieldLabel.Text = 'Pmin Bounds';
            app.PminBoundsEditField = uieditfield(app.OptimizeTab, 'numeric');
            app.PratioBoundsEditFieldLabel = uilabel(app.OptimizeTab);
            app.PratioBoundsEditFieldLabel.Text = 'Pratio Bounds';
            app.PratioBoundsEditField = uieditfield(app.OptimizeTab, 'numeric');
            app.TmaxBoundsEditFieldLabel = uilabel(app.OptimizeTab);
            app.TmaxBoundsEditFieldLabel.Text = 'Tmax  Bounds';
            app.TmaxBoundsEditField = uieditfield(app.OptimizeTab, 'numeric');
            app.UABoundsEditFieldLabel = uilabel(app.OptimizeTab);
            app.UABoundsEditFieldLabel.Text = 'UA Bounds';
            app.UABoundsEditField = uieditfield(app.OptimizeTab, 'numeric');
            app.RecompFractionBoundsEditFieldLabel = uilabel(app.OptimizeTab);
            app.RecompFractionBoundsEditFieldLabel.Text = 'Recomp Fraction Bounds';
            app.RecompFractionBoundsEditField = uieditfield(app.OptimizeTab, 'numeric');
            app.UAsplitBoundsEditFieldLabel = uilabel(app.OptimizeTab);
            app.UAsplitBoundsEditFieldLabel.Text = 'UA split Bounds';
            app.UAsplitBoundsEditField = uieditfield(app.OptimizeTab, 'numeric');
            app.TmaxBoundsEditField_2 = uieditfield(app.OptimizeTab, 'numeric');
            app.TminBoundsEditField_2 = uieditfield(app.OptimizeTab, 'numeric');
            app.PminBoundsEditField_2 = uieditfield(app.OptimizeTab, 'numeric');
            app.UABoundsEditField_2 = uieditfield(app.OptimizeTab, 'numeric');
            app.UAsplitBoundsEditField_2 = uieditfield(app.OptimizeTab, 'numeric');
            app.NetWorkBoundsEditFieldLabel = uilabel(app.OptimizeTab);
            app.NetWorkBoundsEditFieldLabel.Text = 'Net Work Bounds';
            app.NetWorkBoundsEditField = uieditfield(app.OptimizeTab, 'numeric');
            app.RecupeffBoundsEditFieldLabel = uilabel(app.OptimizeTab);
            app.RecupeffBoundsEditFieldLabel.Text = 'Recupeff Bounds';
            app.RecupeffBoundsEditField = uieditfield(app.OptimizeTab, 'numeric');
            app.PreheatBoundsEditFieldLabel = uilabel(app.OptimizeTab);
            app.PreheatBoundsEditFieldLabel.Text = 'Preheat Bounds';
            app.PreheatBoundsEditField = uieditfield(app.OptimizeTab, 'numeric');
            app.CLTRBoundsEditFieldLabel = uilabel(app.OptimizeTab);
            app.CLTRBoundsEditFieldLabel.Text = 'C LTR Bounds';
            app.CLTRBoundsEditField = uieditfield(app.OptimizeTab, 'numeric');
            app.CHTRBoundsEditFieldLabel = uilabel(app.OptimizeTab);
            app.CHTRBoundsEditFieldLabel.Text = 'C HTR Bounds';
            app.CHTRBoundsEditField = uieditfield(app.OptimizeTab, 'numeric');
            app.StartButton_2 = uibutton(app.OptimizeTab, 'push');
            app.StartButton_2.ButtonPushedFcn = createCallbackFcn(app, @StartButton_2Pushed, true);
            app.StartButton_2.Text = 'Start';
            app.NetWorkCheckBox = uicheckbox(app.OptimizeTab);
            app.NetWorkCheckBox.ValueChangedFcn = createCallbackFcn(app, @NetWorkCheckBoxValueChanged, true);
            app.NetWorkCheckBox.Text = 'Net Work';
            app.RecupeffCheckBox = uicheckbox(app.OptimizeTab);
            app.RecupeffCheckBox.ValueChangedFcn = createCallbackFcn(app, @RecupeffCheckBoxValueChanged, true);
            app.RecupeffCheckBox.Text = 'Recup eff';
            app.PReheatCheckBox = uicheckbox(app.OptimizeTab);
            app.PReheatCheckBox.ValueChangedFcn = createCallbackFcn(app, @PReheatCheckBoxValueChanged, true);
            app.PReheatCheckBox.Text = 'P Reheat';
            app.CLTRCheckBox = uicheckbox(app.OptimizeTab);
            app.CLTRCheckBox.ValueChangedFcn = createCallbackFcn(app, @CLTRCheckBoxValueChanged, true);
            app.CLTRCheckBox.Text = 'C LTR';
            app.CHTRCheckBox = uicheckbox(app.OptimizeTab);
            app.CHTRCheckBox.ValueChangedFcn = createCallbackFcn(app, @CHTRCheckBoxValueChanged, true);
            app.CHTRCheckBox.Text = 'C HTR';
            app.PratioBoundsEditField_2 = uieditfield(app.OptimizeTab, 'numeric');
            app.RecompFractionBoundsEditField_2 = uieditfield(app.OptimizeTab, 'numeric');
            app.NetWorkBoundsEditField_2 = uieditfield(app.OptimizeTab, 'numeric');
            app.RecupeffBoundsEditField_2 = uieditfield(app.OptimizeTab, 'numeric');
            app.PreheatBoundsEditField_2 = uieditfield(app.OptimizeTab, 'numeric');
            app.CLTRBoundsEditField_2 = uieditfield(app.OptimizeTab, 'numeric');
            app.CHTRBoundsEditField_2 = uieditfield(app.OptimizeTab, 'numeric');
            app.MinLabel = uilabel(app.OptimizeTab);
            app.MinLabel.Text = 'Min';
            app.MaxLabel = uilabel(app.OptimizeTab);
            app.MaxLabel.Text = 'Max';
            app.ResultsTab = uitab(app.TabGroup);
            app.ResultsTab.Title = 'Results';
            app.iseneff = uieditfield(app.UIFigure, 'numeric');
            app.TsDiagramCheckBox = uicheckbox(app.UIFigure);
            app.TsDiagramCheckBox.ValueChangedFcn = createCallbackFcn(app, @TsDiagramCheckBoxValueChanged, true);
            app.TsDiagramCheckBox.Text = 'Ts Diagram';
            app.PlotsensstudyCheckBox = uicheckbox(app.UIFigure);
            app.PlotsensstudyCheckBox.ValueChangedFcn = createCallbackFcn(app, @PlotsensstudyCheckBoxValueChanged, true);
            app.PlotsensstudyCheckBox.Text = 'Plot (sens study)';
            app.SolveTypeEditFieldLabel = uilabel(app.UIFigure);
            app.SolveTypeEditFieldLabel.Visible = 'off';
            app.SolveTypeEditFieldLabel.Text = 'SolveType';
            app.SolveTypeEditField = uieditfield(app.UIFigure, 'numeric');
            app.SolveTypeEditField.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BOP3_3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end